classdef demente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu            matlab.ui.container.Menu
        Menu_2          matlab.ui.container.Menu
        Menu2           matlab.ui.container.Menu
        MainLayout      matlab.ui.container.GridLayout
        LabelContainer  LabelContainer
        AuxLayout       matlab.ui.container.GridLayout
        AuxImage1       matlab.ui.control.UIAxes
        AuxImage2       matlab.ui.control.UIAxes
        CentralLayout   matlab.ui.container.GridLayout
        ImageLayout     matlab.ui.container.GridLayout
        mouseInfoLabel  matlab.ui.control.Label
        MainImage       matlab.ui.control.UIAxes
        ButtonLayout    matlab.ui.container.GridLayout
        nextButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        currentImage % Description
        imageIdx = 1; % Description
        dataPath = "./data"; % Description
    
        rgbFrames = {};
    end
    
    methods (Access = private)
        
        function path = getIdxPath(app)
            path = fullfile(app.rgbFrames(app.imageIdx).folder, app.rgbFrames(app.imageIdx).name);
        end
        
        function results = removeLabelFcn(app)
            % not used i guess
        end

        % call this whenever you want to show a new image + masks
        function displayImage(app, rgb)
            % get the masks
            [p,f, ~]=fileparts(rgb);
            maskDir =fullfile(p,f);
            if ~isfolder(maskDir)
                error("masks not present for frame: " + f)
            end


            showImage(app, app.AuxImage1, rgb);
            showImage(app, app.AuxImage2, rgb);
            showImage(app, app.MainImage, rgb);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Fill figure with axes and remove tick labels
            app.MainImage.Position = [0 0 app.UIFigure.Position(3:4)];
            app.MainImage.XLabel.String = '';
            app.MainImage.YLabel.String = '';
            
            axtoolbar(app.AuxImage1,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.AuxImage2,{"pan","zoomin","zoomout",'restoreview'});
            axtoolbar(app.MainImage,{"pan","zoomin","zoomout",'restoreview'});

            % get rgb folder
            rgbPath = fullfile(app.dataPath,"test_data/");
            % get the list of frames
            app.rgbFrames = dir(fullfile(rgbPath, "*.png"));

            % TODO replace these three calls with a single function
            imgPath = fullfile(app.rgbFrames(1).folder, app.rgbFrames(1).name);
            app.displayImage(imgPath);

            app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)',0,0,0,0,0);

        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if app.imageIdx == length(app.rgbFrames)
                error("foto finite")
                return
            end

            app.imageIdx = app.imageIdx + 1;
            imagePath = app.getIdxPath();
            showImage(app, app.AuxImage1, imagePath);
            showImage(app, app.AuxImage2, imagePath);
            showImage(app, app.MainImage, imagePath);

            if app.imageIdx == length(app.rgbFrames)
                app.nextButton.Enable = false;
                return
            end
            

            % if foto finite
            % disable this button
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            [x,y,rgb] = getMouseXY(app, app.MainImage);
            
            if x == x % is not nan
                row = ceil(y);
                col = ceil(x);
                app.mouseInfoLabel.Text = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)', row, col, rgb(1), rgb(2), rgb(3));
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            disp("ai klikkato!");
            [x,y,rgb] = getMouseXY(app, app.MainImage);
            
            if x == x % is not nan
                row = ceil(y);
                col = ceil(x);
                s = sprintf('Pixel location: (%d, %d) RGB value: (%d,%d,%d)', row, col, rgb(1), rgb(2), rgb(3));
                disp(s)
            else
                disp("ai klikkato ma xò nn nell immagine!!")
            end
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            showImage(app, app.MainImage, loadImage);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Menu';
            app.Menu_2 = uimenu(app.Menu);
            app.Menu_2.Text = 'Menu';
            app.Menu2 = uimenu(app.UIFigure);
            app.Menu2.Text = 'Menu2';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.CentralLayout = uigridlayout(app.MainLayout);
            app.ButtonLayout = uigridlayout(app.CentralLayout);
            app.nextButton = uibutton(app.ButtonLayout, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = '>';
            app.ImageLayout = uigridlayout(app.CentralLayout);
            app.MainImage = uiaxes(app.ImageLayout);
            zlabel(app.MainImage, 'Z')
            app.MainImage.XTick = [];
            app.MainImage.YTick = [];
            app.mouseInfoLabel = uilabel(app.ImageLayout);
            app.AuxLayout = uigridlayout(app.MainLayout);
            app.AuxImage2 = uiaxes(app.AuxLayout);
            zlabel(app.AuxImage2, 'Z')
            app.AuxImage2.XTick = [];
            app.AuxImage2.YTick = [];
            app.AuxImage1 = uiaxes(app.AuxLayout);
            zlabel(app.AuxImage1, 'Z')
            app.AuxImage1.XTick = [];
            app.AuxImage1.YTick = [];
            app.LabelContainer = LabelContainer(app.MainLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end